classdef DemoApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        AxesImg1       matlab.ui.control.UIAxes
        AxesImg2       matlab.ui.control.UIAxes
        btnDisplayImg  matlab.ui.control.Button
        btnFill        matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnDisplayImg
        function btnDisplayImgButtonPushed(app, event)
            % Get the pathname and filename
            [File_Name, Path_Name] = uigetfile('*.tif','*.jpg');
            % Show the image in the GUI axes
            DemoApp.imgMatrix = imread([Path_Name,File_Name]);
            imshow(DemoApp.imgMatrix, 'Parent', app.AxesImg1);
        end

        % Button pushed function: btnFill
        function btnFillButtonPushed(app, event)
            
        [File_Name, Path_Name] = uigetfile('*.tif','*.jpg');
            % Show the image in the GUI axes
            DemoApp.imgMatrix = imread([Path_Name,File_Name]);
            A = imread([Path_Name,File_Name]);
       bw = ~im2bw(A,0.5); 
       bw =imfill(bw,'holes');


           imshow(bw, 'Parent', app.AxesImg2);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.AxesImg1 = uiaxes(app.UIFigure);
            title(app.AxesImg1, '')
            xlabel(app.AxesImg1, '')
            ylabel(app.AxesImg1, '')
            app.AxesImg2 = uiaxes(app.UIFigure);
            title(app.AxesImg2, '')
            xlabel(app.AxesImg2, '')
            ylabel(app.AxesImg2, '')
            app.btnDisplayImg = uibutton(app.UIFigure, 'push');
            app.btnDisplayImg.ButtonPushedFcn = createCallbackFcn(app, @btnDisplayImgButtonPushed, true);
            app.btnDisplayImg.Text = 'Display Image';
            app.btnFill = uibutton(app.UIFigure, 'push');
            app.btnFill.ButtonPushedFcn = createCallbackFcn(app, @btnFillButtonPushed, true);
            app.btnFill.Text = 'fill';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DemoApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end